classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Label                  matlab.ui.control.Label            % Analiza...
        Label2                 matlab.ui.control.Label            % Selecte...
        Panel                  matlab.ui.container.Panel          % Frecven...
        LabelEditField         matlab.ui.control.Label            % Frecven...
        EditField              matlab.ui.control.EditField       
        LabelEditField2        matlab.ui.control.Label            % Frecven...
        EditField2             matlab.ui.control.EditField       
        Panel2                 matlab.ui.container.Panel          % Dimensi...
        LabelNumericEditField  matlab.ui.control.Label            % Diametr...
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % Unghiul...
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % PCD ele...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
        Button                 matlab.ui.control.Button           % Inregis...
        TabGroup               matlab.ui.container.TabGroup       % Analiza...
        Tab                    matlab.ui.container.Tab            % Analiza...
        UIAxes                 matlab.ui.control.UIAxes           % Amplitu...
        UIAxes2                matlab.ui.control.UIAxes           % Putere ...
        Tab2                   matlab.ui.container.Tab            % Comparatie
        UIAxes3                matlab.ui.control.UIAxes           % Comparatie
        Label3                 matlab.ui.control.Label            % Rezulta...
        Label4                 matlab.ui.control.Label            % Putem o...
        EditField3             matlab.ui.control.EditField       
        Label5                 matlab.ui.control.Label            % cel din...
        Label6                 matlab.ui.control.Label            % -
        Label7                 matlab.ui.control.Label            % -
        Label8                 matlab.ui.control.Label            % frecven...
        EditField4             matlab.ui.control.EditField       
        Label9                 matlab.ui.control.Label            % cu cele...
        Label10                matlab.ui.control.Label            % din bib...
        EditField5             matlab.ui.control.EditField       
        Button6                matlab.ui.control.Button           % Salveaz...
        Button2                matlab.ui.control.Button           % Iesire
        Button3                matlab.ui.control.Button           % Reda in...
        LabelDropDown2         matlab.ui.control.Label            % Limba
        DropDown2              matlab.ui.control.DropDown         % Romana,...
        LabelDropDown          matlab.ui.control.Label            % Serie r...
        DropDown               matlab.ui.control.DropDown         % , 628, ...
        Button4                matlab.ui.control.Button           % Analize...
        Button5                matlab.ui.control.Button           % Opreste...
        LabelSwitch            matlab.ui.control.Label            % Pauza
        Switch                 matlab.ui.control.Switch           % Off, On
        Button7                matlab.ui.control.Button           % Ajutor
        Panel3                 matlab.ui.container.Panel          % Instruc...
        Label11                matlab.ui.control.Label            % Aplicat...
        Button8                matlab.ui.control.Button           % Inchide
    end

    
    properties (Access = public)
        file % loaded audio file
        selectedBearing
        bearings
        fc
        fb
        selectedLanguage
        language
        pl
        stopButtonPressed
        f
        S
        x
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Panel3.Visible = 'off';
            app.bearings = loadBearings();
            value = app.DropDown2.Value;
            if(strcmp(value, 'Romana') == 1) 
               app.language = loadLanguageRomana();
            end
              if(strcmp(value, 'English') == 1)
               app.language = loadLanguageEnglish();
            end
              if(strcmp(value, 'Francais') == 1)
               app.language = loadLanguageFrancais();
            end  
            if(strcmp(value, 'Deutsch') == 1)
               app.language = loadLanguageDeutsch();
            end
        end

        % Button button pushed function
        function loadAudioFile(app)
            file = loadFile();
            app.file = strcat('uploaded_audio/', file);
        end

        % Button3 button pushed function
        function playAudio(app)
            [y, Fs] = audioread(app.file);
            app.pl=audioplayer(y,Fs);
            play(app.pl);
            app.stopButtonPressed = false;
        end

        % DropDown value changed function
        function selectBearing(app, event)
            if(strcmp(app.DropDown.Value, '628') == 1)
                app.selectedBearing = 1;
            end
            if (strcmp(app.DropDown.Value, '608 nou') == 1)
               app.selectedBearing = 2;
            end
            if (strcmp(app.DropDown.Value, '608 vechi') == 1)
              app.selectedBearing = 3;
            end
            getRotationFrequency(app);
            getOrbitalFrequency(app);
            getDiameter(app);
            getPCD(app);
            getAngle(app);
                       
        end

        % TextArea value changed function
        function getFrequency(app)
            
            
        end

        % EditField value changed function
        function getRotationFrequency(app)
            value = app.EditField.Value;
            app.fb = app.bearings(app.selectedBearing).getRotationFrequency();
            app.EditField.Value = num2str(app.fb);
            
        end

        % EditField2 value changed function
        function getOrbitalFrequency(app)
            value = app.EditField2.Value;
            app.fc = app.bearings(app.selectedBearing).getOrbitalFrequency();
            app.EditField2.Value = num2str(app.fc);
        end

        % NumericEditField value changed function
        function getDiameter(app)
            value = app.NumericEditField.Value;
            Da = app.bearings(app.selectedBearing).getDiameter();
            app.NumericEditField.Value = Da;
            
        end

        % NumericEditField4 value changed function
        function getPCD(app)
            value = app.NumericEditField4.Value;
            PCD = app.bearings(app.selectedBearing).getPCD();
            app.NumericEditField4.Value = PCD;
        end

        % NumericEditField3 value changed function
        function getAngle(app)
            value = app.NumericEditField3.Value;
            angle = app.bearings(app.selectedBearing).getAngle();
            app.NumericEditField3.Value = angle;
        end

        % DropDown2 value changed function
        function changeLanguage(app, event)
            value = app.DropDown2.Value;
            if(strcmp(value, 'Romana') == 1) 
               app.language = loadLanguageRomana();
            end
              if(strcmp(value, 'English') == 1)
               app.language = loadLanguageEnglish();
            end
              if(strcmp(value, 'Francais') == 1)
               app.language = loadLanguageFrancais();
            end  
            if(strcmp(value, 'Deutsch') == 1)
               app.language = loadLanguageDeutsch();
            end
            
            app.Label.Text = app.language(1);
            app.LabelDropDown2.Text = app.language(2);
            app.Label2.Text = app.language(3);
            app.LabelDropDown.Text = app.language(4);
            app.Panel.Title = char(app.language(5));
            app.LabelEditField.Text = app.language(6);
            app.LabelEditField2.Text = app.language(7);
            app.Panel2.Title = char(app.language(8));
            app.LabelNumericEditField.Text = app.language(9);
            app.LabelNumericEditField4.Text = app.language(10);
            app.LabelNumericEditField3.Text = app.language(11);
            app.Button.Text = app.language(12);
            app.Button3.Text = app.language(13);
            app.Button4.Text = app.language(35);
            app.Tab.Title = char(app.language(14));
            app.Tab2.Title = char(app.language(15));
            app.UIAxes.Title.String = app.language(16);
            app.UIAxes.YLabel.String = app.language(17);
            app.UIAxes.XLabel.String = app.language(20);            
            app.UIAxes2.Title.String = app.language(18);
            app.UIAxes2.YLabel.String = app.language(19);
            app.UIAxes2.XLabel.String = app.language(20);        
            app.UIAxes3.Title.String = app.language(22);
            app.UIAxes3.YLabel.String = app.language(23);
            app.UIAxes3.XLabel.String = app.language(24);
            app.Button2.Text = app.language(21);
            app.Label3.Text = app.language(25);
            app.Label4.Text = app.language(26);
            app.Label5.Text = app.language(27);
            app.Label8.Text = app.language(28);
            app.Label9.Text = app.language(29);
            app.Label10.Text = app.language(30);
            app.Button7.Text = app.language(36);
            app.Panel3.Title = char(app.language(37));
            app.Button8.Text = app.language(38);
            app.Label11.Text = app.language(39);
            app.Button5.Text = app.language(40);
            app.LabelSwitch.Text = app.language(41);
            app.Button6.Text = app.language(42);
        end

        % Button4 button pushed function
        function analyzeSound(app)
            [y, Fs] = audioread(app.file);
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            N = length(y(:,1));
            Y = fft(y(:,1));
            plot_S(1/Fs, Y, app.UIAxes);
            plot_S(1/Fs, (abs(Y).^2)/N, app.UIAxes2);
            
            [orbitOK, ballOK, app.f, app.S, app.x] = plot_S_v2(1/Fs, Y, app.UIAxes3, app.fc, app.fb);
            if (orbitOK == 1 && ballOK == 1)
                app.EditField3.Value = app.language{31}; 
                app.EditField4.Value = app.language{33};
            else 
                app.EditField3.Value = app.language{32};
                app.EditField4.Value = app.language{34};
            end
            app.EditField5.Value = '+-5 Hz';
        end

        % Button2 button pushed function
        function exitApp(app)
            delete(app);
        end

        % Button5 button pushed function
        function stopSound(app)
           % if(isplaying(app.pl) == 1)
               stop(app.pl);
               app.Switch.Value = 'On';
               app.stopButtonPressed = true;
           % end
        end

        % Switch value changed function
        function pauseSound(app)
            value = app.Switch.Value;
            if(strcmp(value, 'On') == 1 && isplaying(app.pl) == 0 && app.stopButtonPressed == false)
                resume(app.pl);
            end
            if(strcmp(value, 'Off') == 1 && isplaying(app.pl) == 1)
                pause(app.pl);
            end
            
        end

        % Button6 button pushed function
        function saveGraphic(app)
            fig = figure('Visible', 'off');
            plot(app.f, app.S);
            hold on;
            plot(app.f,app.x);
            xlim([0 100]);
             saveas(fig, 'Comparatie.png');
        end

        % Button7 button pushed function
        function showHelpPanel(app)
            app.Panel3.Visible = 'on';
        end

        % Button8 button pushed function
        function hideHelpPanel(app)
            app.Panel3.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Analiza spectrala a lagarelor cu rulmenti';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Selecteaza rulmentul din biblioteca';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Frecventele rulmentului selectat';
            app.Panel.Units = 'pixels';
            app.LabelEditField = uilabel(app.Panel);
            app.LabelEditField.Text = 'Frecventa de rotatie (Hz)';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @getRotationFrequency);
            app.EditField.Editable = 'off';
            app.LabelEditField2 = uilabel(app.Panel);
            app.LabelEditField2.Text = 'Frecventa de orbita (Hz)';
            app.EditField2 = uieditfield(app.Panel, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @getOrbitalFrequency);
            app.EditField2.Editable = 'off';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Dimensiunile rulmentului selectat';
            app.Panel2.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel2);
            app.LabelNumericEditField.Text = 'Diametrul elementelor de rulare (mm)';
            app.NumericEditField = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @getDiameter);
            app.NumericEditField.Editable = 'off';
            app.LabelNumericEditField3 = uilabel(app.Panel2);
            app.LabelNumericEditField3.Text = 'Unghiul de contact (deg)';
            app.NumericEditField3 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @getAngle);
            app.NumericEditField3.Editable = 'off';
            app.LabelNumericEditField4 = uilabel(app.Panel2);
            app.LabelNumericEditField4.Text = 'PCD elementelor de rulare (mm)';
            app.NumericEditField4 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField4.ValueChangedFcn = createCallbackFcn(app, @getPCD);
            app.NumericEditField4.Editable = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @loadAudioFile);
            app.Button.Text = 'Inregistreaza zgomot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Analiza spectrala';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Amplitudine frecventa');
            xlabel(app.UIAxes, 'frecventa (Hz)');
            ylabel(app.UIAxes, 'amplitudine (dB)');
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Putere frecventa');
            xlabel(app.UIAxes2, 'frecventa (Hz)');
            ylabel(app.UIAxes2, 'putere (dB)');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Comparatie';
            app.UIAxes3 = uiaxes(app.Tab2);
            title(app.UIAxes3, 'Comparatie');
            xlabel(app.UIAxes3, 'Frecventa (Hz)');
            ylabel(app.UIAxes3, 'Amplitudine (dB)');
            app.Label3 = uilabel(app.Tab2);
            app.Label3.Text = 'Rezultatul analizei:';
            app.Label4 = uilabel(app.Tab2);
            app.Label4.Text = 'Putem observa ca rulmentul inregistrat:';
            app.EditField3 = uieditfield(app.Tab2, 'text');
            app.EditField3.Editable = 'off';
            app.Label5 = uilabel(app.Tab2);
            app.Label5.Text = 'cel din biblioteca.';
            app.Label6 = uilabel(app.Tab2);
            app.Label6.Text = '-';
            app.Label7 = uilabel(app.Tab2);
            app.Label7.Text = '-';
            app.Label8 = uilabel(app.Tab2);
            app.Label8.Text = 'frecventele';
            app.EditField4 = uieditfield(app.Tab2, 'text');
            app.EditField4.Editable = 'off';
            app.Label9 = uilabel(app.Tab2);
            app.Label9.Text = 'cu cele ale rulmentului';
            app.Label10 = uilabel(app.Tab2);
            app.Label10.Text = 'din biblioteca, cu intervalul de eroare';
            app.EditField5 = uieditfield(app.Tab2, 'text');
            app.EditField5.Editable = 'off';
            app.Button6 = uibutton(app.Tab2, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @saveGraphic);
            app.Button6.Text = 'Salveaza grafic';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @exitApp);
            app.Button2.Text = 'Iesire';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @playAudio);
            app.Button3.Text = 'Reda inregistrarea';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Limba';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'Romana', 'English', 'Francais', 'Deutsch'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @changeLanguage, true);
            app.DropDown2.Value = 'Romana';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Serie rulment';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'', '628', '608 nou', '608 vechi'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @selectBearing, true);
            app.DropDown.Value = '';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @analyzeSound);
            app.Button4.Text = 'Analizeaza zgomot';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @stopSound);
            app.Button5.Text = 'Opreste inregistrarea';
            app.LabelSwitch = uilabel(app.UIFigure);
            app.LabelSwitch.Text = 'Pauza';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @pauseSound);
            app.Switch.Value = 'On';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @showHelpPanel);
            app.Button7.Text = 'Ajutor';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Instructiuni de utilizare';
            app.Panel3.Units = 'pixels';
            app.Label11 = uilabel(app.Panel3);
            app.Label11.Text = {'Aplicatia permite analiza inregistrarii zgomotului produs de rotirea cu o turatie cunoscuta a unui rulment.'; 'Pe baza turatiei, aplicatia calculeaza frecventele de rotatie si de orbita ale rulmentului si compara aceste frecvente cu spectrul zgomotului produs'; 'de rulment. Daca se gasesc spike-uri la acele frecvente (cu o marja de eroare fata de deviatia standard), atunci rulmentul este identificat ca '; 'fiind celdin biblioteca.'; ''; ''; ''; 'Instructiuni de folosire:'; ''; '1. Alegeti un rulment din biblioteca.'; '2. Vor fi calculate frecventele la o turatie standard de 1500 rot/min.'; '3. Apasati pe butonul de "Inregistrare zgomot" pentru a incarca un fisier .wav din sistemul de fisiere.'; '4. Vor fi afisate graficele Amplitudine-Frecventa si Putere-Frecventa in tabul de Analiza spectrala.'; '5. Dupa apasarea butonului "Compara", aplicatia va rula algoritmul de comparatie si, in tabul "Comparatie" va fi afisat graficul '; 'Analiza-Frecventa si  frecventele de interes calculate la pasul 2.'; '6. In dreapta graficului vor fi afisate si niste concluzii trase pe baza acestei analize.'; ''; ''; ''; 'NOTA: Graficele sunt limitate pe axa OX la 0-100 Hz, deoarece acolo este zona de interes (frecvente joase).'};
            app.Button8 = uibutton(app.Panel3, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @hideHelpPanel);
            app.Button8.Text = 'Inchide';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end